classdef SurfaceMetrology < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptimisationLabel               matlab.ui.control.Label
        ModelDesignLabel                matlab.ui.control.Label
        SaTargetValueEditFieldLabel     matlab.ui.control.Label
        SaTargetValueEditField          matlab.ui.control.NumericEditField
        OptimiseButton                  matlab.ui.control.Button
        AnexceliscreatedwiththereLabel  matlab.ui.control.Label
        LoadTrainingDataButton          matlab.ui.control.Button
        TrainModelButton                matlab.ui.control.Button
    end


    properties (Access = public)
        fileName % Description
        filePath % Description
        ANFISModel % Description
    end


    methods (Access = private)

        % Button pushed function: OptimiseButton
        function OptimiseButtonPushed(app, event)
            load('fis1.mat')
            load('fis2.mat')
            load('fis3.mat')
            load('fis4.mat')
            load('fis6.mat')
            load('fis7.mat')
            SaValue=app.SaTargetValueEditField.Value;
            applyOptimisation(SaValue);
        end

        % Button pushed function: LoadTrainingDataButton
        function LoadTrainingDataButtonPushed(app, event)
            [file,path] = uigetfile;
            app.filePath=path;
            app.fileName=file;
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            addpath(app.filePath)
            load(app.fileName)
            run('createANFISModel_RTM.m');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.OptimisationLabel = uilabel(app.UIFigure);
            app.OptimisationLabel.Text = 'Optimisation';
            app.ModelDesignLabel = uilabel(app.UIFigure);
            app.ModelDesignLabel.Text = 'Model Design';
            app.SaTargetValueEditFieldLabel = uilabel(app.UIFigure);
            app.SaTargetValueEditFieldLabel.Text = 'Sa Target Value';
            app.SaTargetValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.SaTargetValueEditField.Limits = [0.244 0.625];
            app.SaTargetValueEditField.ValueDisplayFormat = '%.3f';
            app.SaTargetValueEditField.Value = 0.345;
            app.OptimiseButton = uibutton(app.UIFigure, 'push');
            app.OptimiseButton.ButtonPushedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.OptimiseButton.Text = 'Optimise';
            app.AnexceliscreatedwiththereLabel = uilabel(app.UIFigure);
            app.AnexceliscreatedwiththereLabel.Text = 'An excel is created describing the input values required to achieve the Sa target value';
            app.LoadTrainingDataButton = uibutton(app.UIFigure, 'push');
            app.LoadTrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainingDataButtonPushed, true);
            app.LoadTrainingDataButton.Text = 'Load Training Data';
            app.TrainModelButton = uibutton(app.UIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SurfaceMetrology()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end